classdef Fungal_T2M_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T2M                     matlab.ui.Figure
        PanelResults            matlab.ui.container.Panel
        PanelResults_2          matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        SettingsTab             matlab.ui.container.Tab
        PanelEnvironment        matlab.ui.container.Panel
        minLabel                matlab.ui.control.Label
        CNMin                   matlab.ui.control.NumericEditField
        maxLabel                matlab.ui.control.Label
        CNMax                   matlab.ui.control.NumericEditField
        TauMin                  matlab.ui.control.NumericEditField
        TauMax                  matlab.ui.control.NumericEditField
        minLabel_2              matlab.ui.control.Label
        NPMin                   matlab.ui.control.NumericEditField
        midLabel                matlab.ui.control.Label
        NPMid                   matlab.ui.control.NumericEditField
        maxLabel_2              matlab.ui.control.Label
        NPMax                   matlab.ui.control.NumericEditField
        KappaMin                matlab.ui.control.NumericEditField
        KappaMid                matlab.ui.control.NumericEditField
        KappaMax                matlab.ui.control.NumericEditField
        DeltaLabel              matlab.ui.control.Label
        Delta                   matlab.ui.control.NumericEditField
        NolevelsLabel           matlab.ui.control.Label
        Res1                    matlab.ui.control.NumericEditField
        CNLabel                 matlab.ui.control.Label
        TauhLabel               matlab.ui.control.Label
        NPLabel                 matlab.ui.control.Label
        KappaLabel              matlab.ui.control.Label
        DensityEditFieldLabel   matlab.ui.control.Label
        Density                 matlab.ui.control.NumericEditField
        PanelOrganism           matlab.ui.container.Panel
        NigmlLabel              matlab.ui.control.Label
        Nin                     matlab.ui.control.NumericEditField
        PigmlLabel              matlab.ui.control.Label
        Pin                     matlab.ui.control.NumericEditField
        AlphaLabel              matlab.ui.control.Label
        Alpha                   matlab.ui.control.NumericEditField
        BetaLabel               matlab.ui.control.Label
        Beta                    matlab.ui.control.NumericEditField
        EpsilonLabel            matlab.ui.control.Label
        Epsilon                 matlab.ui.control.NumericEditField
        LambdaLabel             matlab.ui.control.Label
        Lambda                  matlab.ui.control.NumericEditField
        RunModelButton          matlab.ui.control.Button
        CiNiLabel               matlab.ui.control.Label
        CigmlLabel              matlab.ui.control.Label
        Cin                     matlab.ui.control.NumericEditField
        CUELabel_2              matlab.ui.control.Label
        CUE                     matlab.ui.control.NumericEditField
        StrategyCostsLabel      matlab.ui.control.Label
        NiPiRatio               matlab.ui.control.Label
        NiPiLabel               matlab.ui.control.Label
        CiPiRatio               matlab.ui.control.Label
        CiPiLabel               matlab.ui.control.Label
        CiNiRatio               matlab.ui.control.Label
        CiNiPiLabel             matlab.ui.control.Label
        CiNiPiRatio             matlab.ui.control.Label
        CtgmlLabel              matlab.ui.control.Label
        CinTotal                matlab.ui.control.NumericEditField
        DisplayPanel            matlab.ui.container.Panel
        DisplayLabel            matlab.ui.control.Label
        DisplayDropDown         matlab.ui.control.DropDown
        OrganismDropDownLabel   matlab.ui.control.Label
        OrganismDropDown        matlab.ui.control.DropDown
        UpdateButton            matlab.ui.control.Button
        PrintModelButton        matlab.ui.control.Button
        DisplayOverlayResourcesCheckBox  matlab.ui.control.CheckBox
        ColormapLabel           matlab.ui.control.Label
        ColormapDropDown        matlab.ui.control.DropDown
        SpecificResourcesPanel  matlab.ui.container.Panel
        Resource1DropDownLabel  matlab.ui.control.Label
        ResourceDropDown_1      matlab.ui.control.DropDown
        Resource2DropDownLabel  matlab.ui.control.Label
        ResourceDropDown_2      matlab.ui.control.DropDown
        Resource4DropDownLabel  matlab.ui.control.Label
        ResourceDropDown_4      matlab.ui.control.DropDown
        ResourceDropDown_3      matlab.ui.control.DropDown
        Resource3DropDownLabel  matlab.ui.control.Label
        RunResourceButton       matlab.ui.control.Button
        ResourceLabel_1         matlab.ui.control.Label
        TauhEditFieldLabel      matlab.ui.control.Label
        ResourceTauEditField_1  matlab.ui.control.NumericEditField
        ResourceLabel_2         matlab.ui.control.Label
        ResourceTauEditField_2  matlab.ui.control.NumericEditField
        ResourceLabel_3         matlab.ui.control.Label
        ResourceTauEditField_3  matlab.ui.control.NumericEditField
        ResourceLabel_4         matlab.ui.control.Label
        ResourceTauEditField_4  matlab.ui.control.NumericEditField
        ResourceLabel_CNP       matlab.ui.control.Label
        PrintResourcesButton    matlab.ui.control.Button
        ResourcesTab            matlab.ui.container.Tab
        ResourceTable           matlab.ui.control.Table
        ResourceAddButton       matlab.ui.control.Button
        ResourceRemoveButton    matlab.ui.control.Button
        ResourceUpdateButton    matlab.ui.control.Button
        ResourceLoadButton      matlab.ui.control.Button
        ResourceSaveButton      matlab.ui.control.Button
        ResourcePanelSave       matlab.ui.control.Button
    end

    
    properties (Access = private)
        Mu % array of Mu results
        MuPlot % Mu plot data
        DPlot % digestion plot data
        Recalcitrance
        C_to_N_vector
        kappa_vector % Description
        ResourceTableIdx % row and column index for the selected cell in the resource table
        AxisOffset = [0.03 -0.03 0.035 0.035]; % Description
    end
    
    
    methods (Access = private)
        
        function [Mu_cell, Mu_immobile, Mu_motile, Mu_autolytic, Mu_fungal, Recalcitrance, C_to_N_vector] = func_vary_CNP_and_tau(app, N_to_P, kappa)
            
            C_to_N_min = app.CNMin.Value;
            C_to_N_max = app.CNMax.Value;
            tau_min = app.TauMin.Value;
            tau_max = app.TauMax.Value;
            % res1 is the number of different values for the C:N ratio and
            % recalcitrance that are tried
            res1 = app.Res1.Value;
            % Ci is the mass of carbon needed per unit volume of growth, in g per ml
            Ci = app.CinTotal.Value;
            Ni = app.Nin.Value;
            Pi = app.Pin.Value;
            % dry weight in grams per ml of the substrate
            density = app.Density.Value;
            % epsilon is the efficiency of recycling for senscent cells
            epsilon = app.Epsilon.Value;
            % alpha is the mass of machinery needed for cell mobility, relative
            % to the mass of essential machinery
            alpha = app.Alpha.Value;
            % beta is the mass of material in vesicles, relative to the mass of
            % the rest of the fungus, including hydrolases
            beta = app.Beta.Value;
            % lambda is the maximum rate of resource use per unit volume,
            % in g per ml per hour
            lambda = app.Lambda.Value;
            delta = app.Delta.Value;
            
            % xres determines the number of different values for x that are tried in
            % finding the optimal solution. Final value found is accurate to
            % xres^2
            xres = 200;
            
            % sres is the number of iterations used in finding best solution for
            % a given environment and a given rate of sythesis
            sres = 60;
            
            % L is the maximum rate of resource use per unit volume,
            % relative to Ci + Ni + Pi
            L = lambda/(Ci + Ni + Pi);
            
            C_to_N_vector = zeros(res1, 1);
            Recalcitrance = zeros(res1, 1);
            
            Mu_immobile = zeros(res1);
            Mu_motile = zeros(res1);
            Mu_autolytic = zeros(res1);
            Mu_fungal = zeros(res1);
            Mu_cell = zeros(res1);
            
            x_immobile = zeros(res1);
            x_motile = zeros(res1);
            x_autolytic = zeros(res1);
            x_fungal = zeros(res1);
            xC_fungal = zeros(res1);
            xN_fungal = zeros(res1);
            xP_fungal = zeros(res1);
            x_cell = zeros(res1);
            
            for i = 1:res1
                
                C_to_N = exp(log(C_to_N_min) + ...
                    (log(C_to_N_max) - log(C_to_N_min))*(i-1)/(res1-1));
                
                C_to_N_vector(i) = C_to_N;
                
                M_tot = 12*C_to_N*N_to_P + 14*N_to_P + 31;
                
                Ce = density*12*C_to_N*N_to_P/M_tot;
                Ne = density*14*N_to_P/M_tot;
                Pe = density*31/M_tot;
                
                for j = 1:res1
                    
                    tau = exp(log(tau_min) + ...
                        (log(tau_max) - log(tau_min))*(j-1)/(res1-1));
                    
                    Recalcitrance(j) = tau;
                    
                    [Mu_cell(i,j), x_cell(i,j)] = find_best_cell ...
                        (Ci, Ni, Pi, Ce, Ne, tau, delta, L);
                    
                    [Mu_immobile(i,j), x_immobile(i,j)] = find_best_immobile ...
                        (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, sres, xres);
                    
                    [Mu_motile(i,j), x_motile(i,j)] = find_best_motile ...
                        (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, alpha, sres, xres);
                    
                    [Mu_autolytic(i,j), ~, x_autolytic(i,j)] = find_best_autolytic ...
                        (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, epsilon, sres, xres);
                    
                    [Mu_fungal(i,j), x_fungal(i,j), xC_fungal(i,j), ...
                        xN_fungal(i,j), xP_fungal(i,j)] = find_best_fungi( ...
                        Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, beta, sres, 2*xres);
                end
            end
            
            
        end
        
        function [Mu_immobile, Mu_motile, Mu_autolytic, Mu_fungal, D_immobile, D_motile, D_autolytic, D_fungal,DC_fungal,DN_fungal,DP_fungal, kappa_vector] = func_vary_kappa(app,N_to_P, k_min, k_max, C_to_N, tau)
            % k_min and k_max are the minimum and maximum values for the resource
            % acquisition length that we try
            % tau is the time in hours for hydrolases to digest their own mass
            
            % res1 is the number of different values for kappa that we try
            res1 = app.Res1.Value;
            % xres is the number of iterations used in finding best solution for
            % a given environment and a given rate of sythesis
            xres = 200;
            % sres is the number of iterations used in finding best solution for
            % a given environment and a given rate of sythesis
            sres = 60;
            
            % Ci is the mass of carbon needed per unit volume of growth, in g per ml
            Ci = app.CinTotal.Value;
            Ni = app.Nin.Value;
            Pi = app.Pin.Value;
            % dry weight in grams per ml of the substrate
            density = app.Density.Value;
            % epsilon is the efficiency of recycling for autolytic cells
            epsilon = app.Epsilon.Value;
            % alpha is the mass of machinery needed for cell mobility, relative
            % to the mass of essential machinery
            alpha = app.Alpha.Value;
            % beta is the mass of material in vesicles, relative to the mass of
            % the rest of the fungus, including hydrolases
            beta = app.Beta.Value;
            % lambda is the maximum rate of resource use per unit volume,
            % in g per ml per hour
            lambda = app.Lambda.Value;
            % delta is the minimal fraction of C that must be digested in order to
            % digest the available N
            delta = app.Delta.Value;
            
            C_to_P = C_to_N*N_to_P;
            
            M_tot = 12*C_to_P + 14*N_to_P + 31;
            Ce = density*12*C_to_P/M_tot;
            Ne = density*14*N_to_P/M_tot;
            Pe = density*31/M_tot;
            
            % L is the maximum rate of resource use per unit volume,
            % relative to Ci + Ni + Pi
            L = lambda/(Ci + Ni + Pi);
            
            % phi is the correction term
            phi = (Ci + Ni)/(Ci + Ni + Pi);
            
            kappa_vector = zeros(res1, 1);
            
            % apparent growth rates for each category of organism
            Mu_immobile = zeros(res1, 1);
            Mu_motile = zeros(res1, 1);
            Mu_autolytic = zeros(res1, 1);
            Mu_fungal = zeros(res1, 1);
            
            % rate of digestion per unit volume for each category of organism
            D_immobile = zeros(res1, 1);
            D_motile = zeros(res1, 1);
            D_autolytic = zeros(res1, 1);
            D_fungal = zeros(res1, 1);
            DC_fungal = zeros(res1, 1);
            DP_fungal = zeros(res1, 1);
            DN_fungal = zeros(res1, 1);
            
            for i = 1:res1
                
                kappa = k_min + (k_max - k_min)*(i-1)/(res1-1);
                kappa_vector(i) = kappa;
                
                [M, x] = find_best_immobile...
                    (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, sres, xres);
                
                Mu_immobile(i) = M;
                D_immobile(i) = phi*x/tau;
                
                [M, x] = find_best_motile ...
                    (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, alpha, sres, xres);
                
                Mu_motile(i) = M;
                D_motile(i) = phi*x/tau;
                
                [M, ~, x] = find_best_autolytic ...
                    (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, epsilon, sres, xres);
                
                Mu_autolytic(i) = M;
                D_autolytic(i) = phi*x/tau;
                
                [M, x, xC, xN, xP] = find_best_fungi...
                    (Ci, Ni, Pi, Ce, Ne, Pe, kappa, tau, delta, L, beta, sres, 3*xres);
                
                Mu_fungal(i) = M;
                DC_fungal(i) = phi*xC/tau;
                DN_fungal(i) = phi*xN/tau;
                DP_fungal(i) = phi*xP/tau;
                D_fungal(i) = phi*x/tau;
            end
        end
        
        function funcUpdateDisplay(app)
            h = allchild(app.PanelResults);
            delete(h)
            NPVector = [app.NPMin.Value,app.NPMid.Value,app.NPMax.Value];
            kappaVector = [app.KappaMin.Value,app.KappaMid.Value,app.KappaMax.Value];
            %app.PanelResults.AutoResizeChildren = 'off';
            [Ticks.YPos, Ticks.YLabel] = funcGetAxisTicks(app, app.CNMin.Value, app.CNMax.Value);
            [Ticks.XPos, Ticks.XLabel] = funcGetAxisTicks(app, app.TauMin.Value, app.TauMax.Value);
            CTitle{1} = {'\fontsize{14}N limited'};
            %             CTitle{2} = {'\fontsize{14}N:P Ratio',''};
            CTitle{2} = {''};
            CTitle{3} = {'\fontsize{14}P limited'};
            RTitle = {'\fontsize{16}\leftarrow Increasing total resource available per unit volume','\fontsize{6}'};
            n = 0;
            map = colorcet(app.ColormapDropDown.Value);
            for iKappa = 1:3
                for iN2P = 1:3
                    n = n+1;
                    ax = subplot(4,3,n,'Parent',app.PanelResults);
                    ax.Position = ax.Position + app.AxisOffset;
                    switch app.DisplayDropDown.Value
                        case 'CYM'
                            imRGB = func_generate_CYM_merge(app, app.Mu.immobile{iKappa,iN2P}, app.Mu.motile{iKappa,iN2P}, app.Mu.autolytic{iKappa,iN2P},app.Mu.fungal{iKappa,iN2P});
                        case 'Growth'
                             imRGB = funcGenerateGrowthImage(app,iKappa,iN2P,app.OrganismDropDown.Value,map);
                        case 'Ratio'
                            imRGB = funcGenerateRatio(app,iKappa,iN2P,app.OrganismDropDown.Value,map);
                    end
                    imagesc(ax,log10(app.Recalcitrance), log10(app.C_to_N_vector),imRGB);
                    funcSetAxisTicks(app,ax,Ticks)
                    CSubTitle = {['\fontsize{13}N:P Ratio = ' num2str(NPVector(iN2P))]};
                    funcSetAxisColumnTitles(app,ax,[iKappa iN2P],CTitle,CSubTitle,{'\fontsize{12}Recalcitrance (h)'})
                    RSubTitle = {['\fontsize{13}Digestion radius \kappa = ' num2str(kappaVector(iKappa))]};
                    funcSetAxisRowTitles(app,ax,[iKappa iN2P],RTitle,RSubTitle, {'\fontsize{12}C:N ratio'})
%                     if app.DisplayOverlayResourcesCheckBox.Value
%                         funcOverlayResources(app,ax,iN2P,NPVector,app.C_to_N_vector,app.Recalcitrance)
%                     end
                end
            end
            ax = subplot(4,3,n+1:n+2,'Parent',app.PanelResults);
            ax.Position = ax.Position + app.AxisOffset;
            funcLegendText(app,ax)
            ax = subplot(4,3,12,'Parent',app.PanelResults);
            ax.Position = ax.Position + app.AxisOffset;
            axis(ax,'off')
            axis(ax,'square')
            switch app.DisplayDropDown.Value
                case 'CYM'
                    funcCYMColorLegend(app,ax)
                case 'Growth'
                    funcGrowthColorBar(app,ax,map)
                case 'Ratio'
                    funcRatioColorBar(app,ax,map)
            end
            drawnow
        end
        
        
        function funcUpdateDisplayCYM(app)
            h = allchild(app.PanelResults);
            delete(h)
            NPVector = [app.NPMin.Value,app.NPMid.Value,app.NPMax.Value];
            kappaVector = [app.KappaMin.Value,app.KappaMid.Value,app.KappaMax.Value];
            %app.PanelResults.AutoResizeChildren = 'off';
            [Ticks.YPos, Ticks.YLabel] = funcGetAxisTicks(app, app.CNMin.Value, app.CNMax.Value);
            [Ticks.XPos, Ticks.XLabel] = funcGetAxisTicks(app, app.TauMin.Value, app.TauMax.Value);
            CTitle{1} = {'\fontsize{14}N limited'};
            %             CTitle{2} = {'\fontsize{14}N:P Ratio',''};
            CTitle{2} = {''};
            CTitle{3} = {'\fontsize{14}P limited'};
            RTitle = {'\fontsize{16}\leftarrow Increasing total resource available per unit volume','\fontsize{6}'};
            n = 0;
            for iKappa = 1:3
                for iN2P = 1:3
                    n = n+1;
                    ax = subplot(4,3,n,'Parent',app.PanelResults);
                    ax.Position = ax.Position + app.AxisOffset;
                    CYM = func_generate_CYM_merge(app, app.Mu.immobile{iKappa,iN2P}, app.Mu.motile{iKappa,iN2P}, app.Mu.autolytic{iKappa,iN2P},app.Mu.fungal{iKappa,iN2P});
                    imagesc(ax,log10(app.Recalcitrance), log10(app.C_to_N_vector),CYM);
                    funcSetAxisTicks(app,ax,Ticks)
                    CSubTitle = {['\fontsize{13}N:P Ratio = ' num2str(NPVector(iN2P))]};
                    funcSetAxisColumnTitles(app,ax,[iKappa iN2P],CTitle,CSubTitle,{'\fontsize{12}Recalcitrance (h)'})
                    RSubTitle = {['\fontsize{13}Digestion radius \kappa = ' num2str(kappaVector(iKappa))]};
                    funcSetAxisRowTitles(app,ax,[iKappa iN2P],RTitle,RSubTitle, {'\fontsize{12}C:N ratio'})
                    if app.DisplayOverlayResourcesCheckBox.Value
                        funcOverlayResources(app,ax,iN2P,NPVector,app.C_to_N_vector,app.Recalcitrance)
                    end
                end
            end
            ax = subplot(4,3,n+1:n+2,'Parent',app.PanelResults);
            ax.Position = ax.Position + app.AxisOffset;
            funcLegendText(app,ax)
            ax = subplot(4,3,12,'Parent',app.PanelResults);
            ax.Position = ax.Position + app.AxisOffset;
            funcCYMColorLegend(app,ax)
            drawnow
        end
        
        function funcUpdateDisplayGrowth(app)
            h = allchild(app.PanelResults);
            delete(h)
            NPVector = [app.NPMin.Value,app.NPMid.Value,app.NPMax.Value];
            kappaVector = [app.KappaMin.Value,app.KappaMid.Value,app.KappaMax.Value];
            % app.PanelResults.AutoResizeChildren = 'off';
            switch app.NPratioDropDown.Value
                case 'min'
                    iN2P = 1;
                case 'mid'
                    iN2P = 2;
                case 'max'
                    iN2P = 3;
            end
            [Ticks.YPos, Ticks.YLabel] = funcGetAxisTicks(app, app.CNMin.Value, app.CNMax.Value);
            [Ticks.XPos, Ticks.XLabel] = funcGetAxisTicks(app, app.TauMin.Value, app.TauMax.Value);
            CTitle{1} = {'\fontsize{14}immobile'};
            CTitle{2} = {'\fontsize{14}autolytic'};
            CTitle{3} = {'\fontsize{14}motile'};
            CTitle{4} = {'\fontsize{14}fungal'};
            RTitle = {'\fontsize{16}\leftarrow Increasing total resource available per unit volume','\fontsize{6}'};
            n = 0;
            for iKappa = 1:3
                for iClass = 1:3
                    n = n+1;
                    ax = subplot(4,3,n,'Parent',app.PanelResults);
                    ax.Position = ax.Position + app.AxisOffset;
                    imGrowth = funcGenerateGrowthImage(app,iKappa,iN2P,iClass);
                    imagesc(ax,log10(app.Recalcitrance), log10(app.C_to_N_vector), imGrowth)
                    funcSetAxisTicks(app,ax,Ticks)
                    CSubTitle = {['\fontsize{13}N:P = ' num2str(NPVector(iN2P))]};
                    funcSetAxisColumnTitles(app,ax,[iKappa iClass],CTitle,CSubTitle,{'\fontsize{12}Recalcitrance (h)'})
                    RSubTitle = {['\fontsize{13}Digestion radius \kappa = ' num2str(kappaVector(iKappa))]};
                    funcSetAxisRowTitles(app,ax,[iKappa iClass],RTitle,RSubTitle, {'\fontsize{12}C:N ratio'})
                    if app.DisplayOverlayResourcesCheckBox.Value
                        funcOverlayResources(app,ax,iN2P,NPVector,app.C_to_N_vector,app.Recalcitrance)
                    end
                end
            end
            map = colorcet('L3');
            funcGrowthColorBar(app,ax,map)
            ax = subplot(4,3,n+1:n+2,'Parent',app.PanelResults);
            ax.Position = ax.Position + app.AxisOffset;
            funcLegendText(app,ax)
        end
        
        function funcUpdateDisplayRatio(app)
            h = allchild(app.PanelResults);
            delete(h)
            NPVector = [app.NPMin.Value,app.NPMid.Value,app.NPMax.Value];
            kappaVector = [app.KappaMin.Value,app.KappaMid.Value,app.KappaMax.Value];
            app.PanelResults.AutoResizeChildren = 'off';
            [Ticks.YPos, Ticks.YLabel] = funcGetAxisTicks(app, app.CNMin.Value, app.CNMax.Value);
            [Ticks.XPos, Ticks.XLabel] = funcGetAxisTicks(app, app.TauMin.Value, app.TauMax.Value);
            CTitle{1} = {'\fontsize{14}N limited'};
            %             CTitle{2} = {'\fontsize{14}N:P Ratio',''};
            CTitle{2} = {''};
            CTitle{3} = {'\fontsize{14}P limited'};
            RTitle = {'\fontsize{16}\leftarrow Increasing total resource available per unit volume','\fontsize{6}'};
            n = 0;
           
            for iKappa = 1:3
                for iN2P = 1:3
                    n = n+1;
                    ax = subplot(4,3,n,'Parent',app.PanelResults);
                    ax.Position = ax.Position + app.AxisOffset;
                    imRatio = funcGenerateRatio(app,iKappa,iN2P,app.OrganismDropDown.Value);
                    imagesc(ax,log(app.Recalcitrance)/log(10), log(app.C_to_N_vector)/log(10),imRatio);
                    funcSetAxisTicks(app,ax,Ticks)
                    CSubTitle = {['\fontsize{13}N:P Ratio = ' num2str(NPVector(iN2P))]};
                    funcSetAxisColumnTitles(app,ax,[iKappa iN2P],CTitle,CSubTitle,{'\fontsize{12}Recalcitrance (h)'})
                    RSubTitle = {['\fontsize{13}Digestion radius \kappa = ' num2str(kappaVector(iKappa))]};
                    funcSetAxisRowTitles(app,ax,[iKappa iN2P],RTitle,RSubTitle, {'\fontsize{12}C:N ratio'})
                    if app.DisplayOverlayResourcesCheckBox.Value
                        funcOverlayResources(app,ax,iN2P,NPVector,app.C_to_N_vector,app.Recalcitrance)
                    end
                end
            end
             map = colorcet('R3');
            funcRatioColorBar(app,ax,map)
            ax = subplot(4,3,n+1:n+2,'Parent',app.PanelResults);
            ax.Position = ax.Position + app.AxisOffset;
            funcLegendText(app,ax)
            
            %             sgtitle(app.PanelResults,['Growth Ratio - fungal to ' app.OrganismDropDown.Value])
        end
        
        function funcUpdateMuPlot(app)
            h = allchild(app.PanelResults_2);
            delete(h)
            t = tiledlayout(app.PanelResults_2,4,2,'TileSpacing',"normal");
            t.Title.String = 'Optimal growth and digestion rates';
            t.YLabel.String = 'Specific resource quality';
            c = [0 0.8 0.8];
            g = [0 0.8 0];
            m = [0.8 0 0.8];
            k = [0.3 0.3 0.3];
            for iR = 1:4
                if iR == 1
                    environment = app.ResourceDropDown_1.Value;
                elseif iR == 2
                    environment = app.ResourceDropDown_2.Value;
                elseif iR == 3
                    environment = app.ResourceDropDown_3.Value;
                else
                    environment = app.ResourceDropDown_4.Value;
                end
                ax1 = nexttile(t);
                hold(ax1,'off')
                plot(ax1,app.kappa_vector, app.MuPlot.immobile{iR}*24, 'Color', k, 'LineStyle','--', 'LineWidth', 1)
                hold(ax1,'on')
                plot(ax1,app.kappa_vector, app.MuPlot.autolytic{iR}*24, 'Color', g, 'LineWidth', 1)
                plot(ax1,app.kappa_vector, app.MuPlot.motile{iR}*24, 'Color', c, 'LineWidth', 1)
                plot(ax1,app.kappa_vector, app.MuPlot.fungal{iR}*24, 'Color', m, 'LineWidth', 1)
                ax1.XLim = [1 8];
                ax1.XLabel.String = 'Digestion radius \kappa';
                ax1.YLabel.String = 'Apparent growth rate (d^{-1})';
                title(ax1,[environment ' - growth rate'])
                ax2 = nexttile(t);
                hold(ax2,'off')
                plot(ax2,app.kappa_vector, app.DPlot.immobile{iR}*24,'Color', k,'LineStyle','--', 'LineWidth', 1)
                hold(ax2,'on')
                plot(ax2,app.kappa_vector, app.DPlot.autolytic{iR}*24, 'Color', g, 'LineWidth', 1)
                plot(ax2,app.kappa_vector, app.DPlot.motile{iR}*24, 'Color', c, 'LineWidth', 1)
                plot(ax2,app.kappa_vector, app.DPlot.fungal{iR}*24, 'Color', m, 'LineWidth', 1)
                ax2.XLim = [1 8];
                ax2.XLabel.String = 'Digestion radius \kappa';
                ax2.YLabel.String = 'Digestion Rate (g ml^{-1} d^{-1})';
                title(ax2,[environment ' - digestion rate'])
                if iR == 4
                    legend(ax2,'Motile', 'Autolytic', 'Fungi', 'Immobile','Orientation','Vertical','Box','off','Location','southoutside','NumColumns',2);
                end
            end
        end
        
        function funcPrintMuPlot(app)
            [file,path] = uiputfile('*.png','Save resource plots','ResourcePlots');
            if ~isempty(file)
                hfig = figure('Position',[100 100 1000 600],'Color','w');
                t = tiledlayout(hfig,2,4,'TileSpacing',"compact");
                labels = {'(a)','(b)','(c)','(d)';'(e)','(f)','(g)','(h)'};
%                 CNPstr = 'C_{E}:N_{E}:P_{E} = ';
                CNPstr = 'C:N:P = ';
                Taustr = ' Tau = ';
                c = [0 0.8 0.8];
                g = [0 0.8 0];
                m = [0.8 0 0.8];
                k = [0.3 0.3 0.3];
                for ia = 1:2
                    for iR = 1:4
                        if iR == 1
                            environment = app.ResourceDropDown_1.Value;
                        elseif iR == 2
                            environment = app.ResourceDropDown_2.Value;
                        elseif iR == 3
                            environment = app.ResourceDropDown_3.Value;
                        else
                            environment = app.ResourceDropDown_4.Value;
                        end
                        environment(1) = upper(environment(1));
                        if ia == 1
                            ax1 = nexttile(t);
                            hold(ax1,'off')
                            plot(ax1,app.kappa_vector, app.DPlot.immobile{iR}*24, 'Color',k,'LineStyle', '--', 'LineWidth', 1)
                            hold (ax1,'on')
                            plot(ax1,app.kappa_vector, app.DPlot.autolytic{iR}*24, 'Color',g, 'LineWidth', 1)
                            plot(ax1,app.kappa_vector, app.DPlot.motile{iR}*24, 'Color',c, 'LineWidth', 1)
                            plot(ax1,app.kappa_vector, app.DPlot.fungal{iR}*24, 'Color',m, 'LineWidth', 1)
                            ax1.XLim = [1 8];
                            ax1.XLabel.String = 'Digestion radius \kappa';
                            ax1.YLabel.String = 'Digestion Rate (g ml^{-1} d^{-1})';
                            TitleStr = {['\fontsize{12}' environment ':'], ['\fontsize{10}' CNPstr app.ResourceLabel_1.Text ], ['\fontsize{10}' Taustr num2str(app.ResourceTauEditField_1.Value) ' h']};
                            title(ax1,TitleStr,'FontWeight','normal')
                            text(ax1,-0.05*ax1.XLim(2),1.2*ax1.YLim(2),labels(ia,iR),'FontSize',14)
                        elseif ia == 2
                            ax2 = nexttile(t);
                            hold(ax2,'off')
                            plot(ax2,app.kappa_vector, app.MuPlot.immobile{iR}*24, 'Color',k,'LineStyle', '--', 'LineWidth', 1)
                            hold(ax2,'on')
                            plot(ax2,app.kappa_vector, app.MuPlot.autolytic{iR}*24, 'Color',g, 'LineWidth', 1)
                            plot(ax2,app.kappa_vector, app.MuPlot.motile{iR}*24, 'Color',c,'LineWidth', 1)
                            plot(ax2,app.kappa_vector, app.MuPlot.fungal{iR}*24, 'Color',m,  'LineWidth', 1)
                            ax2.XLim = [1 8];
                            ax2.XLabel.String = 'Digestion radius \kappa';
                            ax2.YLabel.String = 'Apparent growth rate (d^{-1})';
                            if iR == 4
                                legend(ax2,'Motile', 'Autolytic', 'Fungi', 'Immobile','Orientation','Vertical','Box','off','Location','southeast','NumColumns',1);
                            end
                            text(ax2,-0.05*ax2.XLim(2),1.1*ax2.YLim(2),labels(ia,iR),'FontSize',14)
                        end
                    end
                end
                export_fig(fullfile(path,file),'-png','-pdf',hfig);
            end
        end
        
        function funcUpdateResourceOptions(app)
            app.ResourceDropDown_1.Items = app.ResourceTable.Data(:,1);
            app.ResourceDropDown_2.Items = app.ResourceTable.Data(:,1);
            app.ResourceDropDown_3.Items = app.ResourceTable.Data(:,1);
            app.ResourceDropDown_4.Items = app.ResourceTable.Data(:,1);
        end
        
        function funcUpdateResourceCNP(app)
            for iR = 1:4
                resource = app.(['ResourceDropDown_' num2str(iR)]).Value;
                row = strcmp(resource,app.ResourceTable.Data(:,1));
                C_to_N = str2double(app.ResourceTable.Data{row,2});
                N_to_P = str2double(app.ResourceTable.Data{row,3});
                tau = str2double(app.ResourceTable.Data{row,4});
                CNP = [num2str(C_to_N*N_to_P) ':' num2str(N_to_P) ':' num2str(1)];
                app.(['ResourceLabel_' num2str(iR)]).Text = CNP;
                app.(['ResourceTauEditField_' num2str(iR)]).Value = tau;
            end
        end
        
        function funcUpdateInternalCNP(app)
            Cm = app.Cin.Value/12; % molar amount of C
            Nm = app.Nin.Value/14;
            Pm = app.Pin.Value/31;
            app.CiNiRatio.Text = [num2str(Cm/Nm,'%0.0f') ':' num2str(1)];
            app.NiPiRatio.Text = [num2str(Nm/Pm,'%0.0f') ':' num2str(1)];
            app.CiPiRatio.Text = [num2str(Cm/Pm,'%0.0f') ':' num2str(1)];
            app.CiNiPiRatio.Text = [num2str(Cm/Pm,'%0.0f') ':' num2str(Nm/Pm,'%0.0f') ':' num2str(1)];
            % the total Cin required includes respiratory carbon
            app.CinTotal.Value = app.Cin.Value/app.CUE.Value;
        end
        
        function imCYM = func_generate_CYM_merge(~, ~, Mu_motile, Mu_autolytic, Mu_fungal)
            res1 = length(Mu_fungal);
            %             MG = ones(res1, res1, 3);
            imCYM = ones(res1, res1, 3);
            % if the fastest growing colony has grown by a factor of
            % of n, and a slower growing colony has grown by a factor
            % of m, the channels for the colonies are coloured according
            % with intensity 1 and m/n respectively. Higher n increases the
            % apparent diffence in colour, making a lighter CYM merge.
            n = 1000;
            for i = 1:res1
                for j = 1:res1
                    max_g =  max([Mu_motile(i,j), Mu_fungal(i,j), Mu_autolytic(i,j)]);
                    %                     max_f_or_i = max([Mu_immobile(i,j), Mu_fungal(i,j)]);
                    if max_g > 0
                        imCYM(i,j,2) = ...
                            1 - (1/n)*exp(Mu_fungal(i,j)*log(n)/max_g);
                        imCYM(i,j,3) = ...
                            1 - (1/n)*exp(Mu_autolytic(i,j)*log(n)/max_g);
                        imCYM(i,j,1) = ...
                            1 - (1/n)*exp(Mu_motile(i,j)*log(n)/max_g);
                    end
                    %                     if max_f_or_i > 0
                    %
                    %                         MG(i,j,1) = ...
                    %                             1 - (1/n)*exp(Mu_immobile(i,j)*log(n)/max_f_or_i);
                    %                         MG(i,j,3) = MG(i,j,1);
                    %                         MG(i,j,2) = ...
                    %                             1 - (1/n)*exp(Mu_fungal(i,j)*log(n)/max_f_or_i);
                    %                     end
                end
            end
            
        end
        
        function imGrowth = funcGenerateGrowthImage(app,iKappa,iN2P,iClass,map)
            switch iClass
                case 'immobile'
                    im = app.Mu.immobile{iKappa,iN2P}./app.Mu.cell{iKappa,iN2P};
                case 'autolytic'
                    im = app.Mu.autolytic{iKappa,iN2P}./app.Mu.cell{iKappa,iN2P};
                case 'motile'
                    im = app.Mu.motile{iKappa,iN2P}./app.Mu.cell{iKappa,iN2P};
                case 'fungal'
                    im = app.Mu.fungal{iKappa,iN2P}./app.Mu.cell{iKappa,iN2P};
            end
            imGrowth = applycolourmap(im, map, [0, 1]);
        end
        
        function imRatio = funcGenerateRatio(app,iKappa,iN2P,iClass,map)
            % max_ratio is the ratio between growth rate results in a colour that is
            % indistinguishable from the colour given when the ratio between growth
            % rates is infinite
            max_ratio = 2;
            mu1 = app.Mu.fungal{iKappa,iN2P};
            switch iClass
                case 'immobile'
                    mu2 = app.Mu.immobile{iKappa,iN2P};
                case 'autolytic'
                    mu2 = app.Mu.autolytic{iKappa,iN2P};
                case 'motile'
                    mu2 = app.Mu.motile{iKappa,iN2P};
                case 'fungal'
                    mu2 = app.Mu.fungal{iKappa,iN2P};
            end
            log_ratio = log(mu1) - log(mu2);
            % if mu1 or mu2 are zero
            idx = find(mu1==0 | mu2==0);
            % normalise the ratio to the maximum for display
            Nratio = log_ratio/log(max_ratio);
            GrowthRatio = zeros(size(Nratio));
            % fix the limits
            Nratio(Nratio>1) = 1;
            Nratio(Nratio<-1) = -1;
            GrowthRatio(Nratio>0) = 2 + 2*(Nratio(Nratio>0));
            GrowthRatio(Nratio<0) = 2 - 2*(-Nratio(Nratio<0));
            imRatio = applycolourmap(GrowthRatio, map, [0, 4]);
            % set any nan or inf to white in all three channels
            sz = numel(GrowthRatio);
            idxW = [idx,idx+sz,idx+sz*2];
            imRatio(idxW) = 1;
        end
        
        function cym = ColorLegend(app)
            % we want the sides of the triangle to be 256, so the arena need to be
            % larger than this - we use 300
            zoom = 3;
            sz = 300.*zoom;
            cym = zeros(sz,sz,3);
            %the center offset is half the size
            xoffset = 1.25*sz/2;
            yoffset = sz/2;
            % the radius needs to be:
            radius = zoom.*128./cos(pi/6);
            % get the vertices of the triangle
            for i = 1:3
                theta = pi - (i-1)*2*pi/3;
                [x(i), y(i)] = pol2cart(theta,radius);
                r(i) = round(x(i)+xoffset);
                c(i) = round(y(i)+yoffset);
            end
            % construct a mask
            mask = false(sz,sz);
            mask = roipoly(mask,c,r);
            %
            cym(r(1),c(1),2) = 1;
            cym(r(2),c(2),3) = 1;
            cym(r(3),c(3),1) = 1;
            % convert to distance transform
            cym(:,:,1) = bwdist(cym(:,:,1),'euclidean').*mask -128.*zoom;
            cym(:,:,2) = bwdist(cym(:,:,2),'euclidean').*mask -128.*zoom;
            cym(:,:,3) = bwdist(cym(:,:,3),'euclidean').*mask -128.*zoom;
            % truncate zero values
            cym(cym<0) = 0;
            % normalise
            cym = cym./max(cym(:));
            background = repmat(~mask,1,1,3);
            cym(background) = 1;
        end
        
        function funcCYMColorLegend(app,ax)
            CYM = ColorLegend(app);
            imshow(CYM,'Xdata',[0 1],'YData',[0 1],'Parent',ax)
            axis(ax,'off')
            axis(ax,'square')
            ax.Position = ax.Position + [0 -0.02 0 0 ];
            text(ax,0,0.95,'autolytic',"HorizontalAlignment","center",'FontSize',12)
            text(ax,0.5,0.05,'fungal',"HorizontalAlignment","center","FontSize",12)
            text(ax,1,0.95,'motile','HorizontalAlignment',"center","FontSize",12)
        end
        
        function funcGrowthColorBar(~,ax,map)
            cb = colorbar(ax,'southoutside');
            cb.Position = [0.45 0.23 0.33 0.0121];
            cb.Label.Interpreter = 'latex';
            cb.Label.String = '$\frac{\mbox{Apparent growth rate}}{\mbox{Maximal specific growth rate}}$';
            cb.Label.Units = 'normalized';
            cb.Label.FontSize = 12;
            cb.Label.Position = [-0.5 1 0];
            cb.Direction = 'normal';
            cb.AxisLocation = 'in';
            colormap(ax,map)
        end
        
        function funcRatioColorBar(~,ax,map)
            cb = colorbar(ax,'southoutside');
            cb.Position = [0.45 0.23 0.33 0.0121];
            cb.Label.Interpreter = 'latex';
            cb.Label.String = '$\frac{\mbox{Hyphal growth rate}}{\mbox{Unicell growth rate}}$';
            cb.Label.Units = 'normalized';
            cb.Label.FontSize = 12;
            cb.Label.Position = [-0.5 1 0];
            cb.Limits = [0 1];
            cb.Ticks = [0.0000    0.2075    0.3685    0.5000    0.6315    0.7925    1.0000];
            cb.TickLabelInterpreter = 'Latex';
            cb.TickLabels = [ ...
                "$\frac{\mbox{10}}{\mbox{20}}$", ...
                "$\frac{\mbox{10}}{\mbox{15}}$", ...
                "$\frac{\mbox{10}}{\mbox{12}}$", ...
                "$\frac{\mbox{10}}{\mbox{10}}$", ...
                "$\frac{\mbox{12}}{\mbox{10}}$", ...
                "$\frac{\mbox{15}}{\mbox{10}}$", ...
                "$\frac{\mbox{20}}{\mbox{10}}$"];
            cb.Direction = 'normal';
            cb.AxisLocation = 'in';
            colormap(ax,map)
        end
        
        function [tickPos,tickVal] = funcGetAxisTicks(~,x1,x2)
            scale = [0.01 0.03 0.1 0.3 1 3 10 30 100 300 1000 3000 10000];
            first = find(scale >= x1,1,'first');
            last = find(scale <= x2,1,'last');
            if log10(x2) - log10(x1) > 2
                int = 2;
            else
                int = 1;
            end
            tickVal = scale(first:int:last);
            tickPos = log10(tickVal);
        end
        
        function funcSetAxisTicks(~,ax,Ticks)
            ax.YTick = Ticks.YPos;
            ax.YTickLabels = Ticks.YLabel;
            ax.YDir = 'normal';
            ax.XTick = Ticks.XPos;
            ax.XTickLabels = Ticks.XLabel;
            ax.XDir = 'normal';
            ax.PlotBoxAspectRatio = [1 1 1];
            ax.TickDir = 'out';
        end
        
        function funcSetAxisColumnTitles(~,ax,idx,CTitle,CSubTitle,XLabel)
            if idx(1) == 1
                ax.Title.String = [CTitle{idx(2)}, CSubTitle];
            elseif idx(1) == 3
                ax.XLabel.String = XLabel;
                ax.XLabel.FontWeight = 'normal';
            end
            ax.Title.FontWeight = 'normal';
        end
        
        function funcSetAxisRowTitles(~,ax,idx,RTitle,RSubTitle, YLabel)
            if idx(2) == 1 && idx(1) == 2
                ax.YLabel.String = [RTitle, RSubTitle, YLabel];
            elseif idx(2) == 1
                ax.YLabel.String = [RSubTitle, YLabel];
                %             elseif idx(1) == 3
                %                 ax.YLabel.String = YLabel;
            end
            ax.YLabel.FontWeight = 'normal';
        end
        
        function funcLegendText(app,ax)
            CNPstr = '(C:N:P = ';
            Taustr = ' Tau = ';
            str1 = [upper(app.ResourceDropDown_1.Value(1)) ' = ' pad(app.ResourceDropDown_1.Value,7) CNPstr pad([app.ResourceLabel_1.Text ','],10) Taustr pad(num2str(app.ResourceTauEditField_1.Value),3,'left') ' h)'];
            str2 = [upper(app.ResourceDropDown_2.Value(1)) ' = ' pad(app.ResourceDropDown_2.Value,7) CNPstr pad([app.ResourceLabel_2.Text ','],10) Taustr pad(num2str(app.ResourceTauEditField_2.Value),3,'left') ' h)'];
            str3 = [upper(app.ResourceDropDown_3.Value(1)) ' = ' pad(app.ResourceDropDown_3.Value,7) CNPstr pad([app.ResourceLabel_3.Text ','],10) Taustr pad(num2str(app.ResourceTauEditField_3.Value),3,'left') ' h)'];
            str4 = [upper(app.ResourceDropDown_4.Value(1)) ' = ' pad(app.ResourceDropDown_4.Value,7) CNPstr pad([app.ResourceLabel_4.Text ','],10) Taustr pad(num2str(app.ResourceTauEditField_4.Value),3,'left') ' h)'];
            t = text(ax,0,0,{str1;str2;str3;str4});
            t.FontName = 'Consolas';
            t.HorizontalAlignment = 'Left';
            t.VerticalAlignment = 'Bottom';
            t.FontSize = 12;
            ax.Title.String = [];
            ax.Color = 'none';
            ax.XColor = 'none';
            ax.XAxis.Label.Color=[0 0 0];
            ax.XAxis.Label.Visible='off';
            ax.YColor = 'none';
            ax.YAxis.Label.Color=[0 0 0];
        end
        
        function funcOverlayResources(app,ax,iN2P,NPVector,CNVector,TauVector)
            for iR = 1:4
                resource = app.(['ResourceDropDown_' num2str(iR)]).Value;
                row = strcmp(resource,app.ResourceTable.Data(:,1));
                CNVal = log10(str2double(app.ResourceTable.Data{row,2}));
                NPVal = str2double(app.ResourceTable.Data{row,3});
                [~, NPidx] = min(abs(NPVector-NPVal));
                TauVal = log10(str2double(app.ResourceTable.Data{row,4}));
                if NPidx == iN2P
                    I = getimage(ax);
                    CNidx = find(log10(CNVector) >= CNVal,1,'first');
                    Tauidx = find(log10(TauVector) >= TauVal,1,'first');
                    if ~isempty(CNidx) && ~isempty(Tauidx)
                        pix = I(CNidx,Tauidx,:);
                        pix = rgb2hsv(pix);
                        if pix(3) < 0.8
                            c = 'w';
                        else
                            c = 'k';
                        end
                        text(ax,TauVal,CNVal,upper(resource(1)),'Color',c,'Tag','ResourceLabel')
                    end
                end
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set the default values
            app.NPMin.Value = 5;
            app.NPMid.Value = 20;
            app.NPMax.Value = 40;
            app.KappaMin.Value = 2;
            app.KappaMid.Value = 4;
            app.KappaMax.Value = 6;
            app.CNMin.Value = 8;
            app.CNMax.Value = 300;
            app.TauMin.Value = 0.01;
            app.TauMax.Value = 100;
            app.Res1.Value = 200;
            app.Density.Value = 0.5;
            app.Alpha.Value = 0.02;
            app.Beta.Value = 0.1;
            app.Delta.Value = 0;
            app.Epsilon.Value = 0.5;
            app.Cin.Value = 0.16;
            app.CUE.Value = 0.5;
            app.Nin.Value = 0.032;
            app.Pin.Value = 0.005;
            app.Lambda.Value = 0.3;
            % calculate internal C:N:P ratio and display
            funcUpdateInternalCNP(app)
            % turn off autoresize to allow subplots
            app.PanelResults.AutoResizeChildren = 'off';
            % set up the controls
            app.DisplayDropDown.Value = 'CYM';
            app.ColormapDropDown.Value = 'L3';
            app.ColormapDropDown.Enable = 'off';
            app.ColormapLabel.Enable = 'off';
            app.OrganismDropDown.Enable = 'off';
            app.OrganismDropDownLabel.Enable = 'off';
            % set up some default resources
            resourceType = { ...
                'wood'; ...
                'litter'; ...
                'dung'; ...
                'agar'};
            resourceValue = sprintfc('%0.1f', [ ...
                200,20,40; ...
                62.5,20,20; ...
                20,5,5; ...
                20,5,0.1]);
            app.ResourceTable.Data = [resourceType, resourceValue];
            app.ResourceDropDown_1.Value = resourceType{1,1};
            app.ResourceDropDown_2.Value = resourceType{2,1};
            app.ResourceDropDown_3.Value = resourceType{3,1};
            app.ResourceDropDown_4.Value = resourceType{4,1};
            % update the resource dropdown menus
            funcUpdateResourceOptions(app)
            funcUpdateResourceCNP(app)
            % set the background to white
            app.PanelResults.BackgroundColor = [1 1 1];
            app.PanelResults_2.BackgroundColor = [1 1 1];
        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            N_to_P_limits = [app.NPMin.Value,app.NPMid.Value,app.NPMax.Value];
            kappa_limits = [app.KappaMin.Value,app.KappaMid.Value,app.KappaMax.Value];
            dlg = uiprogressdlg(app.T2M,'Title','Calculating performance for each organism',...
                'Message','','Cancelable',"on");
            n = 0;
            for iKappa = 1:3
                if dlg.CancelRequested
                    break
                end
                for iN2P = 1:3
                    n = n+1;
                    if dlg.CancelRequested
                        break
                    end
                    N_to_P = N_to_P_limits(iN2P);
                    kappa = kappa_limits(iKappa);
                    dlg.Value = n/10;
                    dlg.Message = ['Performance at N:P ratio of ' num2str(N_to_P,2) ' and digestion radius of ' num2str(kappa,2)];
                    [app.Mu.cell{iKappa,iN2P}, ...
                        app.Mu.immobile{iKappa,iN2P}, ...
                        app.Mu.motile{iKappa,iN2P}, ...
                        app.Mu.autolytic{iKappa,iN2P}, ...
                        app.Mu.fungal{iKappa,iN2P}, ...
                        app.Recalcitrance, ...
                        app.C_to_N_vector] = func_vary_CNP_and_tau(app, N_to_P, kappa);
                end
            end
            funcUpdateDisplay(app)
            close(dlg)
        end

        % Button pushed function: PrintModelButton
        function PrintModelButtonPushed(app, event)
            [file,path] = uiputfile('*.png','Save model output','ModelOutput');
            if ~isempty(file)
                [~,fname,~] = fileparts(file);
                fout = [fname '_' app.DisplayDropDown.Value];
                % crop amounts are given as [top,right,bottom,left] in pixels
%                 export_fig(fullfile(path,fout),'-png','-pdf','-c[10 560 10 301]','-r300', app.T2M);
%                 export_fig(fullfile(path,[fout '_2']),'-png','-pdf','-c[10 560 10 301]','-r300','-q101', app.T2M);
                export_fig(fullfile(path,fout),'-pdf','-c[20 580 20 320]','-r180','-q101', app.T2M);
                export_fig(fullfile(path,fout),'-png','-c[20 560 20 300]','-q101', app.T2M);
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            funcUpdateDisplay(app)
%             switch app.DisplayDropDown.Value
%                 case 'CYM'
%                     funcUpdateDisplayCYM(app)
%                 case 'Growth'
%                     funcUpdateDisplayGrowth(app)
%                 case 'Ratio'
%                     funcUpdateDisplayRatio(app)
%             end
            
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(app, event)
            switch app.DisplayDropDown.Value
                case 'CYM'
                    app.ColormapDropDown.Enable = 'off';
                     app.ColormapLabel.Enable = 'off';
                    app.OrganismDropDown.Enable = 'off';
                    app.OrganismDropDownLabel.Enable = 'off';
                case 'Growth'
                    app.ColormapDropDown.Enable = 'on';
                    app.ColormapDropDown.Value = 'L3';
                     app.ColormapLabel.Enable = 'on';
                    app.OrganismDropDown.Enable = 'on';
                    app.OrganismDropDownLabel.Enable = 'on';
                case 'Ratio'
                    app.ColormapDropDown.Enable = 'on';
                    app.ColormapDropDown.Value = 'R2';
                    app.ColormapLabel.Enable = 'on';
                    app.OrganismDropDown.Enable = 'on';
                    app.OrganismDropDownLabel.Enable = 'on';
            end
        end

        % Value changed function: DisplayOverlayResourcesCheckBox
        function DisplayOverlayResourcesCheckBoxValueChanged(app, event)
            if app.DisplayOverlayResourcesCheckBox.Value
            else
                h = findall(app.PanelResults,'Tag','ResourceLabel');
                delete(h)
            end
            
        end

        % Button pushed function: RunResourceButton
        function RunResourceButtonPushed(app, event)
            dlg = uiprogressdlg(app.T2M,'Title','Calculating optimal investment for each resource environment. Please wait...',...
                'Message','','Cancelable',"on");
            n = 0;
            for iR = 1:4
                if dlg.CancelRequested
                    break
                end
                n = n+1;
                resource = app.(['ResourceDropDown_' num2str(iR)]).Value;
                dlg.Message = ['Optimal allocation for ' resource];
                dlg.Value = n/5;
                row = strcmp(resource,app.ResourceTable.Data(:,1));
                C_to_N = str2double(app.ResourceTable.Data{row,2});
                N_to_P = str2double(app.ResourceTable.Data{row,3});
                %                 tau = app.ResourceTable.Data{row,4};
                tau = app.(['ResourceTauEditField_' num2str(iR)]).Value;
                [app.MuPlot.immobile{iR}, app.MuPlot.motile{iR}, app.MuPlot.autolytic{iR}, app.MuPlot.fungal{iR}, ...
                    app.DPlot.immobile{iR}, app.DPlot.motile{iR}, app.DPlot.autolytic{iR}, app.DPlot.fungal{iR}, ...
                    app.DPlot.Cfungal{iR},app.DPlot.Nfungal{iR},app.DPlot.Pfungal{iR}, app.kappa_vector] = func_vary_kappa(app, ...
                    N_to_P, ...
                    1, ...
                    8, ...
                    C_to_N, ...
                    tau);
            end
            funcUpdateMuPlot(app)
        end

        % Button pushed function: PrintResourcesButton
        function PrintResourcesButtonPushed(app, event)
            funcPrintMuPlot(app)
        end

        % Button pushed function: ResourcePanelSave
        function ResourcePanelSaveButtonPushed(app, event)
            [file,path] = uiputfile('*.png','Save model output','ResourcePanel');
            if ~isempty(file)
                [~,fname,~] = fileparts(file);
                screencapture(0,[200 -900 600 1800],'target',fullfile(path,[fname '.png']))
            end
        end

        % Button pushed function: ResourceAddButton
        function ResourceAddButtonPushed(app, event)
            oldData = app.ResourceTable.Data;
            if ~contains(oldData(:,1),'blank')
                newRow = [{'blank'} sprintfc('%0.0f',[0,0,0])];
                app.ResourceTable.Data = [oldData; newRow];
            end
        end

        % Button pushed function: ResourceUpdateButton
        function ResourceUpdateButtonPushed(app, event)
            funcUpdateResourceOptions(app)
            funcUpdateResourceCNP(app)
        end

        % Value changed function: ResourceDropDown_1
        function ResourceDropDown_1ValueChanged(app, event)
            funcUpdateResourceCNP(app)
        end

        % Value changed function: ResourceDropDown_2
        function ResourceDropDown_2ValueChanged(app, event)
            funcUpdateResourceCNP(app)
        end

        % Value changed function: ResourceDropDown_3
        function ResourceDropDown_3ValueChanged(app, event)
            funcUpdateResourceCNP(app)
        end

        % Value changed function: ResourceDropDown_4
        function ResourceDropDown_4ValueChanged(app, event)
            funcUpdateResourceCNP(app)
        end

        % Button pushed function: ResourceSaveButton
        function ResourceSaveButtonPushed(app, event)
            [file,path] = uiputfile('*.mat','Save the resource table','Resource.mat');
            data = app.ResourceTable.Data;
            save(fullfile(path,file),"data")
        end

        % Button pushed function: ResourceLoadButton
        function ResourceLoadButtonPushed(app, event)
            [file,path] = uigetfile('*.mat','Load a saved resource table','Resource.mat');
            if ~isempty(file)
                temp = load(fullfile(path,file));
                if isfield(temp,'data') && ~isempty(temp.data)
                    app.ResourceTable.Data = temp.data;
                    funcUpdateResourceOptions(app)
                    funcUpdateResourceCNP(app)
                end
            end
        end

        % Value changed function: CinTotal
        function CinTotalValueChanged(app, event)
            funcUpdateInternalCNP(app)
        end

        % Value changed function: Nin
        function NinValueChanged(app, event)
            funcUpdateInternalCNP(app)
        end

        % Value changed function: Pin
        function PinValueChanged(app, event)
            funcUpdateInternalCNP(app)
        end

        % Value changed function: Cin
        function CinValueChanged(app, event)
            funcUpdateInternalCNP(app)
        end

        % Value changed function: CUE
        function CUEValueChanged(app, event)
            funcUpdateInternalCNP(app)
        end

        % Button pushed function: ResourceRemoveButton
        function ResourceRemoveButtonPushed(app, event)
            row = app.ResourceTableIdx(1);
            data = app.ResourceTable.Data;
            data(row,:) = [];
            app.ResourceTable.Data = data;
        end

        % Cell selection callback: ResourceTable
        function ResourceTableCellSelection(app, event)
            indices = event.Indices;
            app.ResourceTableIdx = indices;
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.T2M = uifigure('Visible', 'off');
            app.T2M.Name = 'UI Figure';
            app.PanelResults = uipanel(app.T2M);
            app.PanelResults_2 = uipanel(app.T2M);
            app.TabGroup = uitabgroup(app.T2M);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.PanelEnvironment = uipanel(app.SettingsTab);
            app.PanelEnvironment.Title = 'Environmental parameters';
            app.minLabel = uilabel(app.PanelEnvironment);
            app.minLabel.Text = 'min';
            app.CNMin = uieditfield(app.PanelEnvironment, 'numeric');
            app.maxLabel = uilabel(app.PanelEnvironment);
            app.maxLabel.Text = 'max';
            app.CNMax = uieditfield(app.PanelEnvironment, 'numeric');
            app.TauMin = uieditfield(app.PanelEnvironment, 'numeric');
            app.TauMax = uieditfield(app.PanelEnvironment, 'numeric');
            app.minLabel_2 = uilabel(app.PanelEnvironment);
            app.minLabel_2.Text = 'min';
            app.NPMin = uieditfield(app.PanelEnvironment, 'numeric');
            app.midLabel = uilabel(app.PanelEnvironment);
            app.midLabel.Text = 'mid';
            app.NPMid = uieditfield(app.PanelEnvironment, 'numeric');
            app.maxLabel_2 = uilabel(app.PanelEnvironment);
            app.maxLabel_2.Text = 'max';
            app.NPMax = uieditfield(app.PanelEnvironment, 'numeric');
            app.KappaMin = uieditfield(app.PanelEnvironment, 'numeric');
            app.KappaMid = uieditfield(app.PanelEnvironment, 'numeric');
            app.KappaMax = uieditfield(app.PanelEnvironment, 'numeric');
            app.DeltaLabel = uilabel(app.PanelEnvironment);
            app.DeltaLabel.Text = 'Delta';
            app.Delta = uieditfield(app.PanelEnvironment, 'numeric');
            app.NolevelsLabel = uilabel(app.PanelEnvironment);
            app.NolevelsLabel.Text = 'No. levels';
            app.Res1 = uieditfield(app.PanelEnvironment, 'numeric');
            app.CNLabel = uilabel(app.PanelEnvironment);
            app.CNLabel.Text = 'C:N';
            app.TauhLabel = uilabel(app.PanelEnvironment);
            app.TauhLabel.Text = 'Tau (h)';
            app.NPLabel = uilabel(app.PanelEnvironment);
            app.NPLabel.Text = 'N:P';
            app.KappaLabel = uilabel(app.PanelEnvironment);
            app.KappaLabel.Text = 'Kappa';
            app.DensityEditFieldLabel = uilabel(app.PanelEnvironment);
            app.DensityEditFieldLabel.Text = 'Density';
            app.Density = uieditfield(app.PanelEnvironment, 'numeric');
            app.Density.Limits = [0 1];
            app.PanelOrganism = uipanel(app.SettingsTab);
            app.PanelOrganism.Title = 'Organism parameters';
            app.NigmlLabel = uilabel(app.PanelOrganism);
            app.NigmlLabel.Text = 'Ni (g/ml)';
            app.Nin = uieditfield(app.PanelOrganism, 'numeric');
            app.Nin.Limits = [0 1];
            app.Nin.ValueChangedFcn = createCallbackFcn(app, @NinValueChanged, true);
            app.PigmlLabel = uilabel(app.PanelOrganism);
            app.PigmlLabel.Text = 'Pi (g/ml)';
            app.Pin = uieditfield(app.PanelOrganism, 'numeric');
            app.Pin.Limits = [0 1];
            app.Pin.ValueChangedFcn = createCallbackFcn(app, @PinValueChanged, true);
            app.AlphaLabel = uilabel(app.PanelOrganism);
            app.AlphaLabel.Text = 'Alpha';
            app.Alpha = uieditfield(app.PanelOrganism, 'numeric');
            app.BetaLabel = uilabel(app.PanelOrganism);
            app.BetaLabel.Text = 'Beta';
            app.Beta = uieditfield(app.PanelOrganism, 'numeric');
            app.EpsilonLabel = uilabel(app.PanelOrganism);
            app.EpsilonLabel.Text = 'Epsilon';
            app.Epsilon = uieditfield(app.PanelOrganism, 'numeric');
            app.LambdaLabel = uilabel(app.PanelOrganism);
            app.LambdaLabel.Text = 'Lambda';
            app.Lambda = uieditfield(app.PanelOrganism, 'numeric');
            app.RunModelButton = uibutton(app.PanelOrganism, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            app.CiNiLabel = uilabel(app.PanelOrganism);
            app.CiNiLabel.Text = 'Ci:Ni';
            app.CigmlLabel = uilabel(app.PanelOrganism);
            app.CigmlLabel.Text = 'Ci (g/ml)';
            app.Cin = uieditfield(app.PanelOrganism, 'numeric');
            app.Cin.Limits = [0 Inf];
            app.Cin.ValueChangedFcn = createCallbackFcn(app, @CinValueChanged, true);
            app.CUELabel_2 = uilabel(app.PanelOrganism);
            app.CUELabel_2.Text = 'CUE';
            app.CUE = uieditfield(app.PanelOrganism, 'numeric');
            app.CUE.Limits = [0 Inf];
            app.CUE.ValueChangedFcn = createCallbackFcn(app, @CUEValueChanged, true);
            app.StrategyCostsLabel = uilabel(app.PanelOrganism);
            app.StrategyCostsLabel.Text = 'Strategy costs';
            app.NiPiRatio = uilabel(app.PanelOrganism);
            app.NiPiLabel = uilabel(app.PanelOrganism);
            app.NiPiLabel.Text = 'Ni:Pi';
            app.CiPiRatio = uilabel(app.PanelOrganism);
            app.CiPiLabel = uilabel(app.PanelOrganism);
            app.CiPiLabel.Text = 'Ci:Pi';
            app.CiNiRatio = uilabel(app.PanelOrganism);
            app.CiNiPiLabel = uilabel(app.PanelOrganism);
            app.CiNiPiLabel.Text = 'Ci:Ni:Pi';
            app.CiNiPiRatio = uilabel(app.PanelOrganism);
            app.CtgmlLabel = uilabel(app.PanelOrganism);
            app.CtgmlLabel.Text = 'Ct (g/ml)';
            app.CinTotal = uieditfield(app.PanelOrganism, 'numeric');
            app.CinTotal.Limits = [0 Inf];
            app.CinTotal.ValueChangedFcn = createCallbackFcn(app, @CinTotalValueChanged, true);
            app.DisplayPanel = uipanel(app.SettingsTab);
            app.DisplayPanel.Title = 'Display';
            app.DisplayLabel = uilabel(app.DisplayPanel);
            app.DisplayLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.DisplayPanel);
            app.DisplayDropDown.Items = {'CYM', 'Growth', 'Ratio'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Value = 'CYM';
            app.OrganismDropDownLabel = uilabel(app.DisplayPanel);
            app.OrganismDropDownLabel.Text = 'Organism';
            app.OrganismDropDown = uidropdown(app.DisplayPanel);
            app.OrganismDropDown.Items = {'immobile', 'autolytic', 'motile', 'fungal'};
            app.OrganismDropDown.Value = 'autolytic';
            app.UpdateButton = uibutton(app.DisplayPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.PrintModelButton = uibutton(app.DisplayPanel, 'push');
            app.PrintModelButton.ButtonPushedFcn = createCallbackFcn(app, @PrintModelButtonPushed, true);
            app.PrintModelButton.Text = 'Print';
            app.DisplayOverlayResourcesCheckBox = uicheckbox(app.DisplayPanel);
            app.DisplayOverlayResourcesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayOverlayResourcesCheckBoxValueChanged, true);
            app.DisplayOverlayResourcesCheckBox.Text = 'Resources';
            app.DisplayOverlayResourcesCheckBox.Value = true;
            app.ColormapLabel = uilabel(app.DisplayPanel);
            app.ColormapLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.DisplayPanel);
            app.ColormapDropDown.Items = {'L3', 'L8', 'L9', 'R2', 'R3', 'D1A', 'D2', 'D3', 'D9', 'D13'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'L3';
            app.SpecificResourcesPanel = uipanel(app.SettingsTab);
            app.SpecificResourcesPanel.Title = 'Specific Resources';
            app.Resource1DropDownLabel = uilabel(app.SpecificResourcesPanel);
            app.Resource1DropDownLabel.Text = 'Resource 1';
            app.ResourceDropDown_1 = uidropdown(app.SpecificResourcesPanel);
            app.ResourceDropDown_1.Items = {'wood', 'litter', 'dung', 'agar'};
            app.ResourceDropDown_1.ValueChangedFcn = createCallbackFcn(app, @ResourceDropDown_1ValueChanged, true);
            app.ResourceDropDown_1.Value = 'wood';
            app.Resource2DropDownLabel = uilabel(app.SpecificResourcesPanel);
            app.Resource2DropDownLabel.Text = 'Resource 2';
            app.ResourceDropDown_2 = uidropdown(app.SpecificResourcesPanel);
            app.ResourceDropDown_2.Items = {'wood', 'litter', 'dung', 'agar'};
            app.ResourceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ResourceDropDown_2ValueChanged, true);
            app.ResourceDropDown_2.Value = 'litter';
            app.Resource4DropDownLabel = uilabel(app.SpecificResourcesPanel);
            app.Resource4DropDownLabel.Text = 'Resource 4';
            app.ResourceDropDown_4 = uidropdown(app.SpecificResourcesPanel);
            app.ResourceDropDown_4.Items = {'wood', 'litter', 'dung', 'agar'};
            app.ResourceDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ResourceDropDown_4ValueChanged, true);
            app.ResourceDropDown_4.Value = 'agar';
            app.ResourceDropDown_3 = uidropdown(app.SpecificResourcesPanel);
            app.ResourceDropDown_3.Items = {'wood', 'litter', 'dung', 'agar'};
            app.ResourceDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ResourceDropDown_3ValueChanged, true);
            app.ResourceDropDown_3.Value = 'dung';
            app.Resource3DropDownLabel = uilabel(app.SpecificResourcesPanel);
            app.Resource3DropDownLabel.Text = 'Resource 3';
            app.RunResourceButton = uibutton(app.SpecificResourcesPanel, 'push');
            app.RunResourceButton.ButtonPushedFcn = createCallbackFcn(app, @RunResourceButtonPushed, true);
            app.RunResourceButton.Text = 'Run';
            app.ResourceLabel_1 = uilabel(app.SpecificResourcesPanel);
            app.TauhEditFieldLabel = uilabel(app.SpecificResourcesPanel);
            app.TauhEditFieldLabel.Text = 'Tau (h)';
            app.ResourceTauEditField_1 = uieditfield(app.SpecificResourcesPanel, 'numeric');
            app.ResourceTauEditField_1.Limits = [0 Inf];
            app.ResourceLabel_2 = uilabel(app.SpecificResourcesPanel);
            app.ResourceTauEditField_2 = uieditfield(app.SpecificResourcesPanel, 'numeric');
            app.ResourceTauEditField_2.Limits = [0 Inf];
            app.ResourceLabel_3 = uilabel(app.SpecificResourcesPanel);
            app.ResourceTauEditField_3 = uieditfield(app.SpecificResourcesPanel, 'numeric');
            app.ResourceTauEditField_3.Limits = [0 Inf];
            app.ResourceLabel_4 = uilabel(app.SpecificResourcesPanel);
            app.ResourceTauEditField_4 = uieditfield(app.SpecificResourcesPanel, 'numeric');
            app.ResourceTauEditField_4.Limits = [0 Inf];
            app.ResourceLabel_CNP = uilabel(app.SpecificResourcesPanel);
            app.ResourceLabel_CNP.Text = 'Ce:Ne:Pe';
            app.PrintResourcesButton = uibutton(app.SpecificResourcesPanel, 'push');
            app.PrintResourcesButton.ButtonPushedFcn = createCallbackFcn(app, @PrintResourcesButtonPushed, true);
            app.PrintResourcesButton.Text = 'Print';
            app.ResourcesTab = uitab(app.TabGroup);
            app.ResourcesTab.Title = 'Resources';
            app.ResourceTable = uitable(app.ResourcesTab);
            app.ResourceTable.ColumnName = {'Resource'; 'C:N'; 'N:P'; 'Tau'};
            app.ResourceTable.RowName = {};
            app.ResourceTable.ColumnEditable = true;
            app.ResourceTable.CellSelectionCallback = createCallbackFcn(app, @ResourceTableCellSelection, true);
            app.ResourceAddButton = uibutton(app.ResourcesTab, 'push');
            app.ResourceAddButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceAddButtonPushed, true);
            app.ResourceAddButton.Text = 'Add';
            app.ResourceRemoveButton = uibutton(app.ResourcesTab, 'push');
            app.ResourceRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceRemoveButtonPushed, true);
            app.ResourceRemoveButton.Text = 'Remove';
            app.ResourceUpdateButton = uibutton(app.ResourcesTab, 'push');
            app.ResourceUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceUpdateButtonPushed, true);
            app.ResourceUpdateButton.Text = 'Update';
            app.ResourceLoadButton = uibutton(app.ResourcesTab, 'push');
            app.ResourceLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceLoadButtonPushed, true);
            app.ResourceLoadButton.Text = 'Load';
            app.ResourceSaveButton = uibutton(app.ResourcesTab, 'push');
            app.ResourceSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceSaveButtonPushed, true);
            app.ResourceSaveButton.Text = 'Save';
            app.ResourcePanelSave = uibutton(app.ResourcesTab, 'push');
            app.ResourcePanelSave.ButtonPushedFcn = createCallbackFcn(app, @ResourcePanelSaveButtonPushed, true);
            app.ResourcePanelSave.Text = 'Save Panel';
            % Show the figure after all components are created
            app.T2M.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fungal_T2M_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.T2M)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T2M)
        end
    end
end